classdef RFDataAnalys_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        axes2                  matlab.ui.control.UIAxes
        axes1                  matlab.ui.control.UIAxes
        GridAxes1CheckBox      matlab.ui.control.CheckBox
        GridAxes2CheckBox      matlab.ui.control.CheckBox
        LoadDataButton         matlab.ui.control.Button
        FileNameText           matlab.ui.control.Label
        UIDataTable            matlab.ui.control.Table
        ChartTypesAxes_1       matlab.ui.control.DropDown
        XDataPopUpAxes_1       matlab.ui.control.DropDown
        YDataPopUpAxes_1       matlab.ui.control.DropDown
        ZDataPopUpAxes_1       matlab.ui.control.DropDown
        PopUpGroupAxes_1       matlab.ui.control.DropDown
        LogX_Axes1             matlab.ui.control.CheckBox
        LogY_Axes1             matlab.ui.control.CheckBox
        LogZ_Axes1             matlab.ui.control.CheckBox
        InvX_Axes1             matlab.ui.control.CheckBox
        InvY_Axes1             matlab.ui.control.CheckBox
        InvZ_Axes1             matlab.ui.control.CheckBox
        uipanel4               matlab.ui.container.Panel
        uipanel5               matlab.ui.container.Panel
        uibuttongroup1         matlab.ui.container.ButtonGroup
        RB_Axes1_OriginalData  matlab.ui.control.RadioButton
        RB_Axes1_FilteredData  matlab.ui.control.RadioButton
        DrawButton_Axes1       matlab.ui.control.Button
        ChartTypesAxes_2       matlab.ui.control.DropDown
        XDataPopUpAxes_2       matlab.ui.control.DropDown
        YDataPopUpAxes_2       matlab.ui.control.DropDown
        ZDataPopUpAxes_2       matlab.ui.control.DropDown
        PopUpGroupAxes_2       matlab.ui.control.DropDown
        LogX_Axes2             matlab.ui.control.CheckBox
        LogY_Axes2             matlab.ui.control.CheckBox
        LogZ_Axes2             matlab.ui.control.CheckBox
        InvX_Axes2             matlab.ui.control.CheckBox
        InvY_Axes2             matlab.ui.control.CheckBox
        InvZ_Axes2             matlab.ui.control.CheckBox
        DrawButton_Axes2       matlab.ui.control.Button
        uibuttongroup2         matlab.ui.container.ButtonGroup
        RB_Axes2_OriginalData  matlab.ui.control.RadioButton
        RB_Axes2_FilteredData  matlab.ui.control.RadioButton
        text3                  matlab.ui.control.Label
        PopUpMenuFilter1       matlab.ui.control.DropDown
        PopUpMenuFilter2       matlab.ui.control.DropDown
        PopUpMenuFilter3       matlab.ui.control.DropDown
        PopUpNumFilter1        matlab.ui.control.DropDown
        F1MinVal               matlab.ui.control.EditField
        F1MaxVal               matlab.ui.control.EditField
        text4                  matlab.ui.control.Label
        text5                  matlab.ui.control.Label
        PopUpNumFilter2        matlab.ui.control.DropDown
        F2MinVal               matlab.ui.control.EditField
        F2MaxVal               matlab.ui.control.EditField
        text6                  matlab.ui.control.Label
        text7                  matlab.ui.control.Label
        PopUpNumFilter3        matlab.ui.control.DropDown
        F3MinVal               matlab.ui.control.EditField
        F3MaxVal               matlab.ui.control.EditField
        text8                  matlab.ui.control.Label
        text9                  matlab.ui.control.Label
        FilterDataButton       matlab.ui.control.Button
        uipanel7               matlab.ui.container.Panel
        ExportDataButton       matlab.ui.control.Button
        text10                 matlab.ui.control.Label
        text11                 matlab.ui.control.Label
        SelectAllCheckBox      matlab.ui.control.CheckBox
        X1Label                matlab.ui.control.Label
        Y1Label                matlab.ui.control.Label
        Z1Label                matlab.ui.control.Label
        X2Label                matlab.ui.control.Label
        Y2Label                matlab.ui.control.Label
        Z2Label                matlab.ui.control.Label
        ExpandTable            matlab.ui.control.StateButton
        Refline_Axes1          matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        function SetChartTypes(app, hObject, ~, handles)
            ChartTypes = {'Scatter','Scatter Groupe','Pie chart',...
                          'Spider Plot' , 'GScatter' , 'GScatter 3D',...
                          'Stem','GStem', 'GStem 3D',...
                          'Simple Bar chart','Horz Bar chart',...
                          'Bar Multi-Param.', 'Horz Bar Multi-Param.',...
                          'Parallel Coordinates'}
            set(hObject, 'string', ChartTypes);
        end
        
        function SetDataToAxis(app, hObject, ~, handles)
            set(hObject, 'string', fieldnames(app.OriginalStructure));
        end
        
        function SetFilterNumber(app, hObject, ~, handles)
            fn = fieldnames(app.OriginalStructure);
            filter_num_names = cell(length(fieldnames(app.OriginalStructure)),1);
            for k=1:length(fieldnames(app.OriginalStructure))
                if(isnumeric(app.OriginalStructure.(fn{k})))
                     filter_num_names{k} = [fn{k}];
                end
            end
            filter_num_names = filter_num_names(~cellfun('isempty',filter_num_names))
            filter_num_names = flip(filter_num_names);
            set(hObject, 'string', filter_num_names);
        end
        
        function SetGroups(app, hObject, ~, handles)
            fn = fieldnames(app.OriginalStructure); % fieldnames of structure
            names = cell(length(fieldnames(app.OriginalStructure)),1);
            for k=1:length(fieldnames(app.OriginalStructure))
                if(iscategorical(app.OriginalStructure.(fn{k})) )
                     names{k} = [fn{k}];
                end
            end
            names = names(~cellfun('isempty',names));
            set(hObject, 'string', names);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function RFDataAnalys_OpeningFcn(app)
%             
%             set(gcf,'toolbar','figure');
%             
%             app.ZDataPopUpAxes_1.Visible = 'off';
%             app.ZDataPopUpAxes_2.Visible = 'off';
%             app.LogZ_Axes1.Visible = 'off';
%             app.LogZ_Axes2.Visible = 'off';
%             app.InvZ_Axes1.Visible = 'off';
%             app.InvZ_Axes2.Visible = 'off';
%             app.Z1Label.Visible = 'off';
%             app.Z2Label.Visible = 'off';
%             app.PopUpGroupAxes_1.Visible = 'off';
%             app.PopUpGroupAxes_2.Visible = 'off';
%             
%             app.output = hObject;
%             guidata(hObject, handles);
        end

        % Value changed function: ChartTypesAxes_1
        function ChartTypesAxes_1_Callback(app, event)
            
            spider_param_list = app.OriginalDataTable.Properties.VariableNames;
            app.jListAxes1 = java.util.ArrayList;  % any java.util.List will be ok
            for  i=1:length(spider_param_list)
                app.jListAxes1.add(i-1, char(spider_param_list(i)));
            end
            app.jCBListAxes1 = com.mathworks.mwswing.checkboxlist.CheckBoxList(app.jListAxes1);
            ChartTypeSelectIndex = get(app.ChartTypesAxes_1, 'Value');
            
            switch ChartTypeSelectIndex
            case 1 %Scatter Plot - X, Y, Only
            %Off
            set(app.ZDataPopUpAxes_1, 'Visible',false);
            set(app.InvZ_Axes1, 'Visible',false);
            set(app.LogZ_Axes1, 'Visible',false);
            set(app.Z1Label,'Visible', false);
            set(app.PopUpGroupAxes_1, 'Visible' ,false);
            %On
            set(app.XDataPopUpAxes_1, 'Visible',true);
            set(app.YDataPopUpAxes_1, 'Visible',true);
            set(app.InvX_Axes1, 'Visible',true);
            set(app.InvY_Axes1, 'Visible',true);
            set(app.LogX_Axes1, 'Visible',true);
            set(app.LogY_Axes1, 'Visible',true);
            set(app.X1Label,'Visible', true);
            set(app.Y1Label,'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [~,~] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(false)
            refresh(gcf)
            
            case 2 % Group Scatter Plot
            %Off
            set(app.ZDataPopUpAxes_1, 'Visible',false);
            set(app.InvZ_Axes1, 'Visible',false);
            set(app.LogZ_Axes1, 'Visible',false);
            set(app.Z1Label,'Visible', false);
            %on
            set(app.PopUpGroupAxes_1, 'Visible', true);
            set(app.XDataPopUpAxes_1, 'Visible',true);
            set(app.YDataPopUpAxes_1, 'Visible',true);
            set(app.InvX_Axes1, 'Visible',true);
            set(app.InvY_Axes1, 'Visible',true);
            set(app.LogX_Axes1, 'Visible',true);
            set(app.LogY_Axes1, 'Visible',true);
            set(app.X1Label,'Visible', true);
            set(app.Y1Label,'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(false)
            refresh(gcf)
            
            case 3 % Pie
            %Off
            set(app.ZDataPopUpAxes_1, 'Visible',false);
            set(app.InvZ_Axes1, 'Visible',false);
            set(app.LogZ_Axes1, 'Visible',false);
            set(app.Z1Label,'Visible', false);
            set(app.PopUpGroupAxes_1, 'Visible' ,false);
            set(app.Y1Label, 'Visible', false);
            set(app.YDataPopUpAxes_1, 'Visible',false);
            set(app.InvX_Axes1, 'Visible',false);
            set(app.InvY_Axes1, 'Visible',false);
            set(app.LogX_Axes1, 'Visible',false);
            set(app.LogY_Axes1, 'Visible',false);
            %On
            set(app.X1Label, 'Visible', true);
            set(app.XDataPopUpAxes_1, 'Visible',true);
            %CheckBoxList
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(false)
            refresh(gcf)
            
            case 4 %Spider Chart
            %Off
            set(app.ZDataPopUpAxes_1, 'Visible',false);
            set(app.InvZ_Axes1, 'Visible',false);
            set(app.LogZ_Axes1, 'Visible',false);
            set(app.Z1Label, 'Visible', false);
            set(app.PopUpGroupAxes_1,'Visible', false);
            set(app.XDataPopUpAxes_1, 'Visible',false);
            set(app.X1Label, 'Visible',false);
            set(app.XDataPopUpAxes_1, 'Visible',false);
            set(app.YDataPopUpAxes_1, 'Visible',false);
            set(app.InvX_Axes1, 'Visible',false);
            set(app.InvY_Axes1, 'Visible',false);
            set(app.LogX_Axes1, 'Visible',false);
            set(app.LogY_Axes1, 'Visible',false);
            set(app.Y1Label, 'Visible', false);
            %CheckBoxList On
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(true)
            refresh(gcf)
            
            case 5 %
            %On
            set(app.PopUpGroupAxes_1, 'Visible', true);
            set(app.XDataPopUpAxes_1, 'Visible',true);
            set(app.YDataPopUpAxes_1, 'Visible',true);
            set(app.InvX_Axes1, 'Visible',true);
            set(app.InvY_Axes1, 'Visible',true);
            set(app.LogX_Axes1, 'Visible',true);
            set(app.LogY_Axes1, 'Visible',true);
            set(app.X1Label,'Visible', true);
            set(app.Y1Label,'Visible', true)
            %CheckBoxList
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(false)
            refresh(gcf)
            
            case 6
            %On
            set(app.PopUpGroupAxes_1, 'Visible', true);
            set(app.XDataPopUpAxes_1, 'Visible',true);
            set(app.YDataPopUpAxes_1, 'Visible',true);
            set(app.ZDataPopUpAxes_1, 'Visible',true);
            set(app.InvX_Axes1, 'Visible',true);
            set(app.InvY_Axes1, 'Visible',true);
            set(app.InvZ_Axes1, 'Visible',true);
            set(app.LogX_Axes1, 'Visible',true);
            set(app.LogY_Axes1, 'Visible',true);
            set(app.LogZ_Axes1, 'Visible',true);
            set(app.X1Label, 'Visible', true);
            set(app.Y1Label, 'Visible', true);
            set(app.Z1Label, 'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(false)
            refresh(gcf)
            
            case 7 %stem like usual scatter
            %Off
            set(app.ZDataPopUpAxes_1, 'Visible',false);
            set(app.InvZ_Axes1, 'Visible',false);
            set(app.LogZ_Axes1, 'Visible',false);
            set(app.Z1Label, 'Visible', false);
            set(app.PopUpGroupAxes_1, 'Visible', false);
            %On
            set(app.XDataPopUpAxes_1, 'Visible',true);
            set(app.YDataPopUpAxes_1, 'Visible',true);
            set(app.InvX_Axes1, 'Visible',true);
            set(app.InvY_Axes1, 'Visible',true);
            set(app.LogX_Axes1, 'Visible',true);
            set(app.LogY_Axes1, 'Visible',true);
            set(app.X1Label,'Visible', true);
            set(app.Y1Label,'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(false)
            refresh(gcf)
            
            case 8
            %Off
            set(app.ZDataPopUpAxes_1, 'Visible',false);
            set(app.InvZ_Axes1, 'Visible',false);
            set(app.LogZ_Axes1, 'Visible',false);
            set(app.Z1Label,'Visible', false);
            %On
            set(app.PopUpGroupAxes_1, 'Visible', true);
            set(app.XDataPopUpAxes_1, 'Visible',true);
            set(app.YDataPopUpAxes_1, 'Visible',true);
            set(app.InvX_Axes1, 'Visible',true);
            set(app.InvY_Axes1, 'Visible',true);
            set(app.LogX_Axes1, 'Visible',true);
            set(app.LogY_Axes1, 'Visible',true);
            set(app.X1Label, 'Visible', true);
            set(app.Y1Label, 'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(false)
            refresh(gcf)
            
            case 9
            %On
            set(app.PopUpGroupAxes_1, 'Visible', true);
            set(app.XDataPopUpAxes_1, 'Visible',true);
            set(app.YDataPopUpAxes_1, 'Visible',true);
            set(app.ZDataPopUpAxes_1, 'Visible',true);
            set(app.InvX_Axes1, 'Visible',true);
            set(app.InvY_Axes1, 'Visible',true);
            set(app.InvZ_Axes1, 'Visible',true);
            set(app.LogX_Axes1, 'Visible',true);
            set(app.LogY_Axes1, 'Visible',true);
            set(app.LogZ_Axes1, 'Visible',true);
            set(app.X1Label, 'Visible', true);
            set(app.Y1Label, 'Visible', true);
            set(app.Z1Label, 'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(false);
            refresh(gcf)
            
            case 10
            %Off
            set(app.ZDataPopUpAxes_1, 'Visible',false);
            set(app.InvZ_Axes1, 'Visible',false);
            set(app.LogZ_Axes1, 'Visible',false);
            set(app.Z1Label,'Visible', false);
            set(app.PopUpGroupAxes_1, 'Visible', false);
            set(app.YDataPopUpAxes_1, 'Visible',false);
            set(app.InvX_Axes1, 'Visible',false);
            set(app.InvY_Axes1, 'Visible',false);
            set(app.LogX_Axes1, 'Visible',false);
            set(app.LogY_Axes1, 'Visible',false);
            set(app.Y1Label, 'Visible', false);
            %On
            set(app.XDataPopUpAxes_1, 'Visible',true);
            set(app.X1Label,'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(false)
            refresh(gcf)
            
            case 11
            %Off
            set(app.ZDataPopUpAxes_1, 'Visible',false);
            set(app.InvZ_Axes1, 'Visible',false);
            set(app.LogZ_Axes1, 'Visible',false);
            set(app.Z1Label,'Visible',false);
            set(app.PopUpGroupAxes_1,'Visible', false);
            set(app.YDataPopUpAxes_1, 'Visible',false);
            set(app.InvX_Axes1, 'Visible',false);
            set(app.InvY_Axes1, 'Visible',false);
            set(app.LogX_Axes1, 'Visible',false);
            set(app.LogY_Axes1, 'Visible',false);
            set(app.Y1Label, 'Visible', false);
            %On
            set(app.XDataPopUpAxes_1, 'Visible',true);
            set(app.X1Label,'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(false)
            refresh(gcf)
            
            case 12
            %Off
            set(app.ZDataPopUpAxes_1, 'Visible',false);
            set(app.InvZ_Axes1, 'Visible',false);
            set(app.LogZ_Axes1, 'Visible',false);
            set(app.Z1Label,'Visible',false);
            set(app.PopUpGroupAxes_1,'Visible', false);
            set(app.XDataPopUpAxes_1, 'Visible',false);
            set(app.YDataPopUpAxes_1, 'Visible',false);
            set(app.InvX_Axes1, 'Visible',false);
            set(app.InvY_Axes1, 'Visible',false);
            set(app.LogX_Axes1, 'Visible',false);
            set(app.LogY_Axes1, 'Visible',false);
            set(app.X1Label,'Visible', false);
            set(app.Y1Label, 'Visible', false);
            %CheckBoxList  ON
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(true)
            refresh(gcf)
            
            case 13
            
            set(app.ZDataPopUpAxes_1, 'Visible',false);
            set(app.InvZ_Axes1, 'Visible',false);
            set(app.LogZ_Axes1, 'Visible',false);
            set(app.Z1Label,'Visible',false);
            set(app.PopUpGroupAxes_1,'Visible', false);
            set(app.XDataPopUpAxes_1, 'Visible',false);
            set(app.YDataPopUpAxes_1, 'Visible',false);
            set(app.InvX_Axes1, 'Visible',false);
            set(app.InvY_Axes1, 'Visible',false);
            set(app.LogX_Axes1, 'Visible',false);
            set(app.LogY_Axes1, 'Visible',false);
            set(app.X1Label,'Visible', false);
            set(app.Y1Label, 'Visible', false);
            %CheckBoxList  ON
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(true)
            refresh(gcf)
            
            case 14
            %Off
            set(app.ZDataPopUpAxes_1, 'Visible',false);
            set(app.InvZ_Axes1, 'Visible',false);
            set(app.LogZ_Axes1, 'Visible',false);
            set(app.Z1Label,'Visible',false);
            set(app.XDataPopUpAxes_1, 'Visible',false);
            set(app.YDataPopUpAxes_1, 'Visible',false);
            set(app.InvX_Axes1, 'Visible',false);
            set(app.InvY_Axes1, 'Visible',false);
            set(app.LogX_Axes1, 'Visible',false);
            set(app.LogY_Axes1, 'Visible',false);
            set(app.X1Label,'Visible', false);
            set(app.Y1Label, 'Visible', false);
            %On
            set(app.PopUpGroupAxes_1,'Visible', true);
            %CheckBoxList  ON
            app.jScrollPane1Axes1 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes1,[330,4,150,105],gcf);
            app.jScrollPane1Axes1.setVisible(true)
            refresh(gcf)
            
            end
            app.output = hObject;
            guidata(hObject, handles);
        end

        % Value changed function: ChartTypesAxes_2
        function ChartTypesAxes_2_Callback(app, event)
            
            spider_param_list = app.OriginalDataTable.Properties.VariableNames;
            app.jListAxes2 = java.util.ArrayList;  % any java.util.List will be ok
            for  i=1:length(spider_param_list)
                    app.jListAxes2.add(i-1, char(spider_param_list(i)));
            end
            app.jCBListAxes2 = com.mathworks.mwswing.checkboxlist.CheckBoxList(app.jListAxes2);
            ChartTypeSelectIndex = get(app.ChartTypesAxes_2, 'Value');
            
            switch ChartTypeSelectIndex
            case 1 %Scatter Plot
            %Off
            set(app.ZDataPopUpAxes_2, 'Visible',false);
            set(app.InvZ_Axes2, 'Visible',false);
            set(app.LogZ_Axes2, 'Visible',false);
            set(app.Z2Label,'Visible', false);
            set(app.PopUpGroupAxes_2, 'Visible' ,false);
            %On
            set(app.XDataPopUpAxes_2, 'Visible',true);
            set(app.YDataPopUpAxes_2, 'Visible',true);
            set(app.InvX_Axes2, 'Visible',true);
            set(app.InvY_Axes2, 'Visible',true);
            set(app.LogX_Axes2, 'Visible',true);
            set(app.LogY_Axes2, 'Visible',true);
            set(app.X2Label,'Visible', true);
            set(app.Y2Label,'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(false)
            refresh(gcf)
            
            case 2 % Group Scatter Plot
            %Off
            set(app.ZDataPopUpAxes_2 ,'Visible',false);
            set(app.InvZ_Axes2, 'Visible',false);
            set(app.LogZ_Axes2, 'Visible',false);
            set(app.Z2Label,'Visible', false);
            %on
            set(app.PopUpGroupAxes_2, 'Visible', true);
            set(app.XDataPopUpAxes_2, 'Visible',true);
            set(app.YDataPopUpAxes_2, 'Visible',true);
            set(app.InvX_Axes2, 'Visible',true);
            set(app.InvY_Axes2, 'Visible',true);
            set(app.LogX_Axes2, 'Visible',true);
            set(app.LogY_Axes2, 'Visible',true);
            set(app.X2Label,'Visible', true);
            set(app.Y2Label,'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(false)
            refresh(gcf)
            
            case 3 % Pie
            %Off
            set(app.ZDataPopUpAxes_2, 'Visible',false);
            set(app.InvZ_Axes2, 'Visible',false);
            set(app.LogZ_Axes2, 'Visible',false);
            set(app.Z2Label,'Visible', false);
            set(app.PopUpGroupAxes_2, 'Visible' ,false);
            set(app.Y2Label, 'Visible', false);
            set(app.YDataPopUpAxes_2, 'Visible',false);
            set(app.InvX_Axes2, 'Visible',false);
            set(app.InvY_Axes2, 'Visible',false);
            set(app.LogX_Axes2, 'Visible',false);
            set(app.LogY_Axes2, 'Visible',false);
            %On
            set(app.X2Label, 'Visible', true);
            set(app.XDataPopUpAxes_2, 'Visible',true);
            %CheckBoxList
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(false)
            refresh(gcf)
            
            case 4 %Spider Chart
            %Off
            set(app.ZDataPopUpAxes_2, 'Visible',false);
            set(app.InvZ_Axes2, 'Visible',false);
            set(app.LogZ_Axes2, 'Visible',false);
            set(app.Z2Label, 'Visible', false);
            set(app.PopUpGroupAxes_2,'Visible', false);
            set(app.XDataPopUpAxes_2, 'Visible',false);
            set(app.X2Label, 'Visible',false);
            set(app.XDataPopUpAxes_2, 'Visible',false);
            set(app.YDataPopUpAxes_2, 'Visible',false);
            set(app.InvX_Axes2, 'Visible',false);
            set(app.InvY_Axes2, 'Visible',false);
            set(app.LogX_Axes2, 'Visible',false);
            set(app.LogY_Axes2, 'Visible',false);
            set(app.Y2Label, 'Visible', false);
            %CheckBoxList On
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(true)
            refresh(gcf)
            
            case 5 %
            %Off
            set(app.PopUpGroupAxes_2, 'Visible', true);
            set(app.XDataPopUpAxes_2, 'Visible',true);
            set(app.YDataPopUpAxes_2, 'Visible',true);
            set(app.InvX_Axes2, 'Visible',true);
            set(app.InvY_Axes2, 'Visible',true);
            set(app.LogX_Axes2, 'Visible',true);
            set(app.LogY_Axes2, 'Visible',true);
            set(app.X2Label,'Visible', true);
            set(app.Y2Label,'Visible', true)
            %CheckBoxList
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(false)
            refresh(gcf)
            
            case 6
                %On
            set(app.PopUpGroupAxes_2, 'Visible', true);
            set(app.XDataPopUpAxes_2, 'Visible',true);
            set(app.YDataPopUpAxes_2, 'Visible',true);
            set(app.ZDataPopUpAxes_2, 'Visible',true);
            set(app.InvX_Axes2, 'Visible',true);
            set(app.InvY_Axes2, 'Visible',true);
            set(app.InvZ_Axes2, 'Visible',true);
            set(app.LogX_Axes2, 'Visible',true);
            set(app.LogY_Axes2, 'Visible',true);
            set(app.LogZ_Axes2, 'Visible',true);
            set(app.X2Label, 'Visible', true);
            set(app.Y2Label, 'Visible', true);
            set(app.Z2Label, 'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(false)
            refresh(gcf)
            case 7 %stem like usual scatter
            %Off
            set(app.ZDataPopUpAxes_2, 'Visible',false);
            set(app.InvZ_Axes2, 'Visible',false);
            set(app.LogZ_Axes2, 'Visible',false);
            set(app.Z2Label, 'Visible', false);
            set(app.PopUpGroupAxes_2, 'Visible', false);
            %On
            set(app.XDataPopUpAxes_2, 'Visible',true);
            set(app.YDataPopUpAxes_2, 'Visible',true);
            set(app.InvX_Axes2, 'Visible',true);
            set(app.InvY_Axes2, 'Visible',true);
            set(app.LogX_Axes2, 'Visible',true);
            set(app.LogY_Axes2, 'Visible',true);
            set(app.X2Label,'Visible', true);
            set(app.Y2Label,'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(false)
            refresh(gcf)
            
            case 8
            %Off
            set(app.ZDataPopUpAxes_2, 'Visible',false);
            set(app.InvZ_Axes2, 'Visible',false);
            set(app.LogZ_Axes2, 'Visible',false);
            set(app.Z2Label,'Visible', false);
            %On
            set(app.PopUpGroupAxes_2, 'Visible', true);
            set(app.XDataPopUpAxes_2, 'Visible',true);
            set(app.YDataPopUpAxes_2, 'Visible',true);
            set(app.InvX_Axes2, 'Visible',true);
            set(app.InvY_Axes2, 'Visible',true);
            set(app.LogX_Axes2, 'Visible',true);
            set(app.LogY_Axes2, 'Visible',true);
            set(app.X2Label, 'Visible', true);
            set(app.Y2Label, 'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(false);
            refresh(gcf)
            
            case 9
            %On
            set(app.PopUpGroupAxes_2, 'Visible', true);
            set(app.XDataPopUpAxes_2, 'Visible',true);
            set(app.YDataPopUpAxes_2, 'Visible',true);
            set(app.ZDataPopUpAxes_2, 'Visible',true);
            set(app.InvX_Axes2, 'Visible',true);
            set(app.InvY_Axes2, 'Visible',true);
            set(app.InvZ_Axes2, 'Visible',true);
            set(app.LogX_Axes2, 'Visible',true);
            set(app.LogY_Axes2, 'Visible',true);
            set(app.LogZ_Axes2, 'Visible',true);
            set(app.X2Label, 'Visible', true);
            set(app.Y2Label, 'Visible', true);
            set(app.Z2Label, 'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(false);
            refresh(gcf)
            
            case 10
            %Off
            set(app.ZDataPopUpAxes_2, 'Visible',false);
            set(app.InvZ_Axes2, 'Visible',false);
            set(app.LogZ_Axes2, 'Visible',false);
            set(app.Z2Label,'Visible', false);
            set(app.PopUpGroupAxes_2, 'Visible', false);
            set(app.YDataPopUpAxes_2, 'Visible',false);
            set(app.InvX_Axes2, 'Visible',false);
            set(app.InvY_Axes2, 'Visible',false);
            set(app.LogX_Axes2, 'Visible',false);
            set(app.LogY_Axes2, 'Visible',false);
            set(app.Y2Label, 'Visible', false);
            %On
            set(app.XDataPopUpAxes_2, 'Visible',true);
            set(app.X2Label,'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(false);
            refresh(gcf)
            
            case 11
            %Off
            set(app.ZDataPopUpAxes_2, 'Visible',false);
            set(app.InvZ_Axes2, 'Visible',false);
            set(app.LogZ_Axes2, 'Visible',false);
            set(app.Z2Label,'Visible',false);
            set(app.PopUpGroupAxes_2,'Visible', false);
            set(app.YDataPopUpAxes_2, 'Visible',false);
            set(app.InvX_Axes2, 'Visible',false);
            set(app.InvY_Axes2, 'Visible',false);
            set(app.LogX_Axes2, 'Visible',false);
            set(app.LogY_Axes2, 'Visible',false);
            set(app.Y2Label, 'Visible', false);
            %On
            set(app.XDataPopUpAxes_2, 'Visible',true);
            set(app.X2Label,'Visible', true);
            %CheckBoxList
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(false);
            refresh(gcf)
            
            case 12
            %Off
            set(app.ZDataPopUpAxes_2, 'Visible',false);
            set(app.InvZ_Axes2, 'Visible',false);
            set(app.LogZ_Axes2, 'Visible',false);
            set(app.Z2Label,'Visible',false);
            set(app.PopUpGroupAxes_2,'Visible', false);
            set(app.XDataPopUpAxes_2, 'Visible',false);
            set(app.YDataPopUpAxes_2, 'Visible',false);
            set(app.InvX_Axes2, 'Visible',false);
            set(app.InvY_Axes2, 'Visible',false);
            set(app.LogX_Axes2, 'Visible',false);
            set(app.LogY_Axes2, 'Visible',false);
            set(app.X2Label,'Visible', false);
            set(app.Y2Label, 'Visible', false);
            %CheckBoxList  ON
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(true);
            refresh(gcf)
            
            case 13
            
            set(app.ZDataPopUpAxes_2,'Visible',false);
            set(app.InvZ_Axes2, 'Visible',false);
            set(app.LogZ_Axes2, 'Visible',false);
            set(app.Z2Label,'Visible',false);
            set(app.PopUpGroupAxes_2,'Visible', false);
            set(app.XDataPopUpAxes_2, 'Visible',false);
            set(app.YDataPopUpAxes_2, 'Visible',false);
            set(app.InvX_Axes2, 'Visible',false);
            set(app.InvY_Axes2, 'Visible',false);
            set(app.LogX_Axes2, 'Visible',false);
            set(app.LogY_Axes2, 'Visible',false);
            set(app.X2Label,'Visible', false);
            set(app.Y2Label, 'Visible', false);
            %CheckBoxList  ON
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(true);
            refresh(gcf)
            
            case 14
            %Off
            set(app.ZDataPopUpAxes_2, 'Visible',false);
            set(app.InvZ_Axes2, 'Visible',false);
            set(app.LogZ_Axes2, 'Visible',false);
            set(app.Z2Label,'Visible',false);
            set(app.XDataPopUpAxes_2, 'Visible',false);
            set(app.YDataPopUpAxes_2, 'Visible',false);
            set(app.InvX_Axes2, 'Visible',false);
            set(app.InvY_Axes2, 'Visible',false);
            set(app.LogX_Axes2, 'Visible',false);
            set(app.LogY_Axes2, 'Visible',false);
            set(app.X2Label,'Visible', false);
            set(app.Y2Label, 'Visible', false);
            %On
            set(app.PopUpGroupAxes_2,'Visible', true);
            %CheckBoxList  ON
            app.jScrollPane1Axes2 = com.mathworks.mwswing.MJScrollPane(app.jCBListAxes2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Axes2,[840,4,150,105],gcf);
            app.jScrollPane1Axes2.setVisible(true);
            refresh(gcf)
            
            end
            app.output = hObject;
            guidata(hObject, handles);
        end

        % Button pushed function: DrawButton_Axes1
        function DrawButton_Axes1_Callback(app, event)
            
            %set axes switches to default
            set(app.GridAxes1CheckBox, 'Value', false);
            %set axes to default
            axes(app.axes1);
            setpixelposition(app.axes1, [60 222 421 331])
            cla;
            reset(gca);
            xlabel('-');
            ylabel('-');
            legend('off');
            
            %Selecting Data
            if (app.RB_Axes1_OriginalData.Value == 1)
                app.DrawData = app.OriginalStructure;
            else
                app.DrawData = app.FilteredStructure;
            end
            if (app.RB_Axes1_FilteredData.Value == 1)
                app.DrawData = app.FilteredStructure;
            else
                app.DrawData = app.OriginalStructure;
            end
            
            %%Selecting XYZ axis
            %======X
            idX = get(app.XDataPopUpAxes_1,'Value');
            itemX = get(app.XDataPopUpAxes_1,'String');
            selectedItemX = itemX{idX};
            %======Y
            idY = get(app.YDataPopUpAxes_1,'Value');
            itemY = get(app.YDataPopUpAxes_1,'String');
            selectedItemY = itemY{idY};
            %======Z
            idZ = get(app.ZDataPopUpAxes_1,'Value');
            itemZ = get(app.ZDataPopUpAxes_1,'String');
            selectedItemZ = itemZ{idZ};
            %====== Groups
            idG = get(app.PopUpGroupAxes_1,'Value');
            itemG = get(app.PopUpGroupAxes_1,'String');
            selectedItemG = itemG{idG};
            
            %====== Set Data to axis
            x=app.DrawData.(selectedItemX);
            y=app.DrawData.(selectedItemY);
            z=app.DrawData.(selectedItemZ);
            
            groups = {app.DrawData.(selectedItemG)};
            
            %Chart Type Selecting
            ChartTypeSelectIndex = get(app.ChartTypesAxes_1, 'Value');
            
            %   Chart Types
            switch ChartTypeSelectIndex
            %Scatter ========================================================================================
            case 1
            axes(app.axes1);
            datacursormode on;
            dcm_obj = datacursormode(gcf);
            set(dcm_obj,'DisplayStyle','Window',...
            'SnapToDataVertex','off','Enable','on')
            sz = 35;
            s = scatter(x,y,sz,'o','k')
            xlabel(selectedItemX);
            ylabel(selectedItemY);
            s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
            s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
            fn = fieldnames(app.OriginalStructure);
            for   datatipindex=1:length(fieldnames(app.DrawData))
                s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
            end
            hold off
            
            % Group Scatter =====================================================================================
            case 2
            
            g = gscatter(x,y,groups,'rkgb','o*hd+',7,'on',selectedItemX,selectedItemY)
            legend_group = char(categories(groups{1}))
            datacursormode on;
            dcm_obj = datacursormode(gcf);
            set(dcm_obj,'DisplayStyle','window',...
                'SnapToDataVertex','off','Enable','on')
            hold on
            sz = 180;
            s = scatter(x,y,sz,'o','w')
            xlabel(selectedItemX);
            ylabel(selectedItemY);
            
            s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
            s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
            fn = fieldnames(app.DrawData);
            for   datatipindex=1:length(fieldnames(app.DrawData))
                s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
            end
            legend(legend_group);
            hold off


            % Pie chart ==========================================================================================
            case 3
            pie(x);
            hold off


            case 4
            % Spider/Radar Chart for selected elements============================================================
            setpixelposition(app.axes1, [70 222 350 270]) %Set new size of axes
            spider_params = app.jCBListAxes1.getCheckedValues; % get list of selected params
            cell_param = {}; % clear list
            for i=1:size(spider_params)
                cell_param(:,i) = cellstr(spider_params.get(i-1)) % ---> to string array
            end
            
            data = app.UIDataTable.Data; % get list of data from disp UItable
            cell_rows = size(data)              % number of rows of UItable
            
            for i=1:cell_rows(1);           % checking 1 row for selected items
                selectedItems(i,1) = cell2mat(data(i,1))
            end
            
            selectedItems = logical(selectedItems); % ==>> transform to logical array
            k = find(selectedItems);                %===find selected items index
            
            if (app.RB_Axes1_OriginalData.Value == 1)
                for j=1:length(k)
                SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            if (app.RB_Axes1_FilteredData.Value == 1)
                for j=1:length(k)
                SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            
            headers = SortedTable.Properties.VariableNames; %get VariableNames of sorted table
            
            for i=1:length(headers)
                app.DrawData.(headers{i}) = table2array(SortedTable(:,i));
            end
            
            % creating structure from selected table elements
                for i=1:length(headers)
                    if (iscell(SortedTable.(headers{i})(1)))
                    app.DrawData.(headers{i}) = categorical(app.DrawData.(headers{i})); %make cells - categorical
                    end
                end
            app.DrawData.(headers{1}) = categorical(app.DrawData.(headers{1})); % Make year - categorical
                % Creating  Matrix of elements for Spider Chart Element (i - number of elements, j)
            for i=1:height(SortedTable)
                for j=1:length(cell_param)
                    Element(i,j) = app.DrawData.(char(cell_param(j)))(i)
                end
            end
            el_num = size(Element)
            % creating Names for selecting elements in spider chart
            for i=1:el_num(1)
                ElementNames(1,i) = string({(k(i))+". "+SortedTable.Author(i)}); % Disply indexes an Autors
            end
            % Drawing Spider chart
            ElementNames  = cellstr(ElementNames);% Transforming to correct type for legend
            ElementNames  = char(ElementNames);
            AxesLabels = cell_param; % Selected Params
            AxesInterval = 5;
            FillOption = 'on';
            FillTransparency = 0.3;
            spider_plot_R2019b(Element,...
                  'AxesLabels', AxesLabels,...
                  'AxesInterval', AxesInterval,...
                  'FillOption', FillOption,...
                  'FillTransparency', FillTransparency);
            legend({ElementNames}); %Legend - ElementNumber - TO DO replace with Original Index and Autor
            hold off
            
            case 5
            
            selected_group = categories(app.DrawData.(selectedItemG)); %selected group for Gscatter
            
            for i=1:length(selected_group)
            
                x=app.DrawData.(selectedItemX)(app.DrawData.(selectedItemG) == selected_group(i));
                y=app.DrawData.(selectedItemY)(app.DrawData.(selectedItemG) == selected_group(i));
                scatter(x,y,35,'d');
                hold on
            
            end
            
            datacursormode on;
            dcm_obj = datacursormode(gcf);
            set(dcm_obj,'DisplayStyle','window',...
                'SnapToDataVertex','off','Enable','on')
            sz = 180;
            x=app.DrawData.(selectedItemX);
            y=app.DrawData.(selectedItemY);
            s = scatter(x,y,sz,'o','w')
            xlabel(selectedItemX);
            ylabel(selectedItemY);
            s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
            s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
            
            fn = fieldnames(app.DrawData);
            for   datatipindex=1:length(fieldnames(app.DrawData))
                s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
            end
            legend({char(selected_group)})
            hold off
            
            case 6
            
            selected_group = categories(app.DrawData.(selectedItemG));
            for i=1:length(selected_group)
                x=app.DrawData.(selectedItemX)(app.DrawData.(selectedItemG) == selected_group(i));
                y=app.DrawData.(selectedItemY)(app.DrawData.(selectedItemG) == selected_group(i));
                z=app.DrawData.(selectedItemZ)(app.DrawData.(selectedItemG) == selected_group(i));
                scatter3(x,y,z,'o');
                hold on
            end
            
            datacursormode on;
            dcm_obj = datacursormode(gcf);
            set(dcm_obj,'DisplayStyle','window',...
            'SnapToDataVertex','off','Enable','on')
            sz = 180;
            x=app.DrawData.(selectedItemX);
            y=app.DrawData.(selectedItemY);
            z=app.DrawData.(selectedItemZ);
            s = scatter3(x,y,z,sz,'o','w')
            
            xlabel(selectedItemX);
            ylabel(selectedItemY);
            zlabel(selectedItemZ);
            
            s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
            s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
            s.DataTipTemplate.DataTipRows(3).Label = selectedItemZ;
            
            fn = fieldnames(app.DrawData);
            for datatipindex=1:length(fieldnames(app.DrawData))
                s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
            end
            legend({char(selected_group)})
            hold off
            
            case 7 % Stem
            
            datacursormode on;
            dcm_obj = datacursormode(gcf);
            set(dcm_obj,'DisplayStyle','Window',...
                'SnapToDataVertex','off','Enable','on')
            sz = 35;
            s = stem(x,y,':k')
            xlabel(selectedItemX);
            ylabel(selectedItemY);
            s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
            s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
            fn = fieldnames(app.OriginalStructure);
            for  datatipindex=1:length(fieldnames(app.DrawData))
                s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
            end
            hold off
            
            case 8 %GStem
            
            selected_group = categories(app.DrawData.(selectedItemG));
            
            for i=1:length(selected_group)
                x=app.DrawData.(selectedItemX)(app.DrawData.(selectedItemG) == selected_group(i));
                y=app.DrawData.(selectedItemY)(app.DrawData.(selectedItemG) == selected_group(i));
                stem(x,y,...
                '--o',...
                'MarkerSize',5,...
                'LineWidth',0.75)
                hold on
            end
            
            datacursormode on;
            dcm_obj = datacursormode(gcf);
            set(dcm_obj,'DisplayStyle','window',...
                 'SnapToDataVertex','off','Enable','on')
            sz = 180;
            hold on
            x=app.DrawData.(selectedItemX);
            y=app.DrawData.(selectedItemY);
            s = scatter(x,y,sz,'o','w')
            xlabel(selectedItemX);
            ylabel(selectedItemY);
            
            s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
            s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
            
            fn = fieldnames(app.DrawData);
            for datatipindex=1:length(fieldnames(app.DrawData))
                s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
            end
            legend({char(selected_group)})
            hold off
            
            case 9
            
            selected_group = categories(app.DrawData.(selectedItemG));
            for i=1:length(selected_group)
                x=app.DrawData.(selectedItemX)(app.DrawData.(selectedItemG) == selected_group(i));
                y=app.DrawData.(selectedItemY)(app.DrawData.(selectedItemG) == selected_group(i));
                z=app.DrawData.(selectedItemZ)(app.DrawData.(selectedItemG) == selected_group(i));
                stem3(x,y,z,'o');
                hold on
            end
            datacursormode on;
            dcm_obj = datacursormode(gcf);
            set(dcm_obj,'DisplayStyle','window',...
                'SnapToDataVertex','off','Enable','on')
            
            sz = 180;
            x=app.DrawData.(selectedItemX);
            y=app.DrawData.(selectedItemY);
            z=app.DrawData.(selectedItemZ);
            s = scatter3(x,y,z,sz,'o','w')
            xlabel(selectedItemX);
            ylabel(selectedItemY);
            zlabel(selectedItemZ);
            
            s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
            s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
            s.DataTipTemplate.DataTipRows(3).Label = selectedItemZ;
            
            fn = fieldnames(app.DrawData);
            for   datatipindex=1:length(fieldnames(app.DrawData))
                s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
            end
            legend({char(selected_group)})
            hold off
            %==============================================================
            case 10
            
            data = app.UIDataTable.Data; % get list of data from disp UItable
            cell_rows = size(data)              % number of rows of UItable
            for i=1:cell_rows(1);           % checking 1 row for selected items
                selectedItems(i,1) = cell2mat(data(i,1))
            end
            
            selectedItems = logical(selectedItems); % ==>> transform to logical array
            k = find(selectedItems);                %===find selected items index
            
            if (app.RB_Axes1_OriginalData.Value == 1)
                for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            if (app.RB_Axes1_FilteredData.Value == 1)
                for j=1:length(k)
                SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            
            for i=1:height(SortedTable) %length selected elements
                BarParameters(i) = app.DrawData.(selectedItemX)(i)
            end
            bar(BarParameters,0.5)
            ylabel(selectedItemX);
            hold off
            
            case 11
            data = app.UIDataTable.Data; % get list of data from disp UItable
            cell_rows = size(data)              % number of rows of UItable
            
            for i=1:cell_rows(1);           % checking 1 row for selected items
                selectedItems(i,1) = cell2mat(data(i,1))
            end
            
            selectedItems = logical(selectedItems); % ==>> transform to logical array
            k = find(selectedItems);                %===find selected items index
            
            if (app.RB_Axes1_OriginalData.Value == 1)
                for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            if (app.RB_Axes1_FilteredData.Value == 1)
                for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            
            for i=1:height(SortedTable) %length selected elements
                BarParameters(i) = app.DrawData.(selectedItemX)(i)
            end
            barh(BarParameters,0.5)
            xlabel(selectedItemX);
            hold off
            
            case 12
            
            bar_params = app.jCBListAxes1.getCheckedValues; % get list of selected params
            cell_param = {}; % clear list
            for i=1:size(bar_params)
                cell_param(:,i) = cellstr(bar_params.get(i-1)) % ---> to string array
            end
            data = app.UIDataTable.Data; % get list of data from disp UItable
            cell_rows = size(data)              % number of rows of UItable
            for i=1:cell_rows(1);           % checking 1 row for selected items
                selectedItems(i,1) = cell2mat(data(i,1))
            end
            selectedItems = logical(selectedItems); % ==>> transform to logical array
            k = find(selectedItems);                %===find selected items index
            
            if (app.RB_Axes1_OriginalData.Value == 1)
                for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            if (app.RB_Axes1_FilteredData.Value == 1)
                for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            headers = SortedTable.Properties.VariableNames; %get VariableNames of sorted table
            
            for i=1:length(headers)
                app.DrawData.(headers{i}) = table2array(SortedTable(:,i));
            end
                % creating structure from selected table elements
            for i=1:length(headers)
                if (iscell(SortedTable.(headers{i})(1)))
                    app.DrawData.(headers{i}) = categorical(app.DrawData.(headers{i})); %make cells - categorical
                end
            end
            app.DrawData.(headers{1}) = categorical(app.DrawData.(headers{1})); % Make year - categorical
            % Creating  Matrix of elements for Spider Chart Element (i - number of elements, j)
            for i=1:height(SortedTable)   %height(SortedTable) % Selected elements
                for j=1:length(cell_param)
                    Element(i,j) = app.DrawData.(char(cell_param(j)))(i)
                end
            end
            bar(Element,'grouped')
            legend({char(cell_param)})
            hold off
            
            case 13
            
            bar_params = app.jCBListAxes1.getCheckedValues; % get list of selected params
            
            cell_param = {}; % clear list
            for i=1:size(bar_params)
                cell_param(:,i) = cellstr(bar_params.get(i-1)) % ---> to string array
            end
            
            data = app.UIDataTable.Data; % get list of data from disp UItable
            cell_rows = size(data)              % number of rows of UItable
            
            for i=1:cell_rows(1);           % checking 1 row for selected items
                selectedItems(i,1) = cell2mat(data(i,1))
            end
            
            selectedItems = logical(selectedItems); % ==>> transform to logical array
            k = find(selectedItems);                %===find selected items index
            
            
            if (app.RB_Axes1_OriginalData.Value == 1)
                for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            if (app.RB_Axes1_FilteredData.Value == 1)
                for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            headers = SortedTable.Properties.VariableNames; %get VariableNames of sorted table
            
            for i=1:length(headers)
                app.DrawData.(headers{i}) = table2array(SortedTable(:,i));
            end
            % creating structure from selected table elements
            for i=1:length(headers)
                if (iscell(SortedTable.(headers{i})(1)))
                    app.DrawData.(headers{i}) = categorical(app.DrawData.(headers{i})); %make cells - categorical
                end
            end
            app.DrawData.(headers{1}) = categorical(app.DrawData.(headers{1})); % Make year - categorical
                % Creating  Matrix of elements for Spider Chart Element (i - number of elements, j)
            for i=1:height(SortedTable)   %height(SortedTable) % Selected elements
                for j=1:length(cell_param)
                    Element(i,j) = app.DrawData.(char(cell_param(j)))(i)
                end
            end
            barh(Element,'grouped')
            legend({char(cell_param)})
            hold off
            
            case 14
            
            paral_params = app.jCBListAxes1.getCheckedValues; % get list of selected params
            
            cell_param = {}; % clear list
            for i=1:size(paral_params)
                cell_param(:,i) = cellstr(paral_params.get(i-1)) % ---> to string array
            end
            
            data = app.UIDataTable.Data; % get list of data from disp UItable
            cell_rows = size(data)              % number of rows of UItable
            for i=1:cell_rows(1);           % checking 1 row for selected items
                selectedItems(i,1) = cell2mat(data(i,1))
            end
            
            selectedItems = logical(selectedItems); % ==>> transform to logical array
            k = find(selectedItems);                %===find selected items index
            
            if (app.RB_Axes1_OriginalData.Value == 1)
                for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            if (app.RB_Axes1_FilteredData.Value == 1)
                for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            for j=1:length(cell_param)
                for i=1:height(SortedTable)
                    ParalelStruct.(cell_param{j})(i) = app.DrawData.(cell_param{j})(i)
                end
                ParalelStruct.(cell_param{j}) = transpose(ParalelStruct.(cell_param{j}))
            end
            ParallelTable = struct2table(ParalelStruct);
            figure;
            p = parallelplot(ParallelTable);
            p.GroupVariable = selectedItemG;
            p.LineStyle = {'-'}
            p.LineWidth = [1.5]
            end
        end

        % Button pushed function: DrawButton_Axes2
        function DrawButton_Axes2_Callback(app, event)
            
            set(app.GridAxes2CheckBox, 'Value', false);
            
            axes(app.axes2);
            
            setpixelposition(app.axes2, [570 221 422 332])
            cla;
            reset(gca);
            xlabel('-');
            ylabel('-');
            legend('off');
            
            
            %Selecting Data
            if (app.RB_Axes2_OriginalData.Value == 1)
                app.DrawData = app.OriginalStructure;
            else
                app.DrawData = app.FilteredStructure;
            end
            if (app.RB_Axes2_FilteredData.Value == 1)
                app.DrawData = app.FilteredStructure;
            else
                app.DrawData = app.OriginalStructure;
            end
            
            %%Selecting XYZ axis
            idX = get(app.XDataPopUpAxes_2,'Value');
            itemX = get(app.XDataPopUpAxes_2,'String');
            selectedItemX = itemX{idX};
            %=========
            idY = get(app.YDataPopUpAxes_2,'Value');
            itemY = get(app.YDataPopUpAxes_2,'String');
            selectedItemY = itemY{idY};
            %==========
            idZ = get(app.ZDataPopUpAxes_2,'Value');
            itemZ = get(app.ZDataPopUpAxes_2,'String');
            selectedItemZ = itemZ{idZ};
            %===========
            idG = get(app.PopUpGroupAxes_2,'Value');
            itemG = get(app.PopUpGroupAxes_2,'String');
            selectedItemG = itemG{idG};
            %============
            x=app.DrawData.(selectedItemX);
            y=app.DrawData.(selectedItemY);
            z=app.DrawData.(selectedItemZ);
            groups = {app.DrawData.(selectedItemG)};
            
            %Chart Type Selecting
            ChartTypeSelectIndex = get(app.ChartTypesAxes_2, 'Value');
            
            switch ChartTypeSelectIndex
            
                %Scatter ================================================================
            case 1
                axes(app.axes2);
                datacursormode on;
                dcm_obj = datacursormode(gcf);
                set(dcm_obj,'DisplayStyle','Window',...
                'SnapToDataVertex','off','Enable','on')
                sz = 35;
                s = scatter(x,y,sz,'o','k')
                xlabel(selectedItemX);
                ylabel(selectedItemY);
                s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
                s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
                fn = fieldnames(app.OriginalStructure);
                for   datatipindex=1:length(fieldnames(app.DrawData))
                    s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
                end
                hold off
            
            
                %Group Scatter =========================================================
            case 2
            
                g = gscatter(x,y,groups,'rkgb','o*hd+',7,'on',selectedItemX,selectedItemY)
                legend_group = char(categories(groups{1}))
                datacursormode on;
                dcm_obj = datacursormode(gcf);
                set(dcm_obj,'DisplayStyle','window',...
                    'SnapToDataVertex','off','Enable','on')
                hold on
                sz = 180;
                s = scatter(x,y,sz,'o','w')
                xlabel(selectedItemX);
                ylabel(selectedItemY);
            
                s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
                s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
                fn = fieldnames(app.DrawData);
                for   datatipindex=1:length(fieldnames(app.DrawData))
                    s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
                end
                legend(legend_group);
                hold off
            
            
                % Pie chart ===============================================================
                case 3
            
                pie(x);
            
                hold off
            
                % Spider/Radar Chart for selected elements==================================
                case 4
            
                setpixelposition(app.axes2, [615 225 320 270])
            
                spider_params = app.jCBListAxes2.getCheckedValues;
            
                cell_param = {};
                for i=1:size(spider_params)
                cell_param(:,i) = cellstr(spider_params.get(i-1))
                end
            
                data = app.UIDataTable.Data;
                cell_rows = size(data)
            
                for i=1:cell_rows(1);
                selectedItems(i,1) = cell2mat(data(i,1))
                end
            
                selectedItems = logical(selectedItems);
                k = find(selectedItems);
            
                if (app.RB_Axes2_OriginalData.Value == 1)
                    for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                else
                    for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                end
                if (app.RB_Axes2_FilteredData.Value == 1)
                    for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                else
                    for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                end
                headers = SortedTable.Properties.VariableNames;
            
                for i=1:length(headers)
                app.DrawData.(headers{i}) = table2array(SortedTable(:,i));
                end
            
                for i=1:length(headers)
                    if (iscell(SortedTable.(headers{i})(1)))
                    app.DrawData.(headers{i}) = categorical(app.DrawData.(headers{i})); %make cells - categorical
                    end
                end
                app.DrawData.(headers{1}) = categorical(app.DrawData.(headers{1})); % Make year - categorical
            
                for i=1:height(SortedTable)
                    for j=1:length(cell_param)
                    Element(i,j) = app.DrawData.(char(cell_param(j)))(i)
                    end
                end
                el_num = size(Element)
                % creating Names for selecting elements in spider chart
                for i=1:el_num(1)
                    ElementNames(1,i) = string({(k(i))+". "+SortedTable.Author(i)}); % Disply indexes an Autors
                end
                % Drawing Spider chart
                ElementNames  = cellstr(ElementNames);% Transforming to correct type for legend
                ElementNames  = char(ElementNames);
                AxesLabels = cell_param; % Axes properties пїЅпїЅпїЅ пїЅпїЅпїЅпїЅпїЅпїЅпїЅпїЅпїЅ
                AxesInterval = 5;
                FillOption = 'on';
                FillTransparency = 0.3;
                spider_plot_R2019b(Element,...
                  'AxesLabels', AxesLabels,...
                  'AxesInterval', AxesInterval,...
                  'FillOption', FillOption,...
                  'FillTransparency', FillTransparency);
                 legend({ElementNames});
            
                 hold off
            case 5
            
                    selected_group = categories(app.DrawData.(selectedItemG)); %selected group for Gscatter
            
                    for i=1:length(selected_group)
            
                        x=app.DrawData.(selectedItemX)(app.DrawData.(selectedItemG) == selected_group(i));
                        y=app.DrawData.(selectedItemY)(app.DrawData.(selectedItemG) == selected_group(i));
                        scatter(x,y,35,'d');
                        hold on
            
                    end
            
                    datacursormode on;
                    dcm_obj = datacursormode(gcf);
                    set(dcm_obj,'DisplayStyle','window',...
                        'SnapToDataVertex','off','Enable','on')
                    sz = 180;
                    x=app.DrawData.(selectedItemX);
                    y=app.DrawData.(selectedItemY);
                    s = scatter(x,y,sz,'o','w')
                    xlabel(selectedItemX);
                    ylabel(selectedItemY);
                    s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
                    s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
            
                    fn = fieldnames(app.DrawData);
                    for   datatipindex=1:length(fieldnames(app.DrawData))
                        s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
                    end
                    legend({char(selected_group)})
                    hold off
            
                case 6
            
                    selected_group = categories(app.DrawData.(selectedItemG));
                    for i=1:length(selected_group)
                        x=app.DrawData.(selectedItemX)(app.DrawData.(selectedItemG) == selected_group(i));
                        y=app.DrawData.(selectedItemY)(app.DrawData.(selectedItemG) == selected_group(i));
                        z=app.DrawData.(selectedItemZ)(app.DrawData.(selectedItemG) == selected_group(i));
                        scatter3(x,y,z,'o');
                        hold on
                    end
            
                    datacursormode on;
                    dcm_obj = datacursormode(gcf);
                    set(dcm_obj,'DisplayStyle','window',...
                    'SnapToDataVertex','off','Enable','on')
                    sz = 180;
                    x=app.DrawData.(selectedItemX);
                    y=app.DrawData.(selectedItemY);
                    z=app.DrawData.(selectedItemZ);
                    s = scatter3(x,y,z,sz,'o','w')
            
                    xlabel(selectedItemX);
                    ylabel(selectedItemY);
                    zlabel(selectedItemZ);
            
                    s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
                    s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
                    s.DataTipTemplate.DataTipRows(3).Label = selectedItemZ;
            
                    fn = fieldnames(app.DrawData);
                    for datatipindex=1:length(fieldnames(app.DrawData))
                        s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
                    end
                    legend({char(selected_group)})
                    hold off
            
                case 7 % Stem
            
                    datacursormode on;
                    dcm_obj = datacursormode(gcf);
                    set(dcm_obj,'DisplayStyle','Window',...
                        'SnapToDataVertex','off','Enable','on')
                    sz = 35;
                    s = stem(x,y,':k')
                    xlabel(selectedItemX);
                    ylabel(selectedItemY);
                    s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
                    s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
                    fn = fieldnames(app.OriginalStructure);
                    for  datatipindex=1:length(fieldnames(app.DrawData))
                        s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
                    end
                    hold off
                case 8 %GStem
            
                    selected_group = categories(app.DrawData.(selectedItemG));
            
                    for i=1:length(selected_group)
                        x=app.DrawData.(selectedItemX)(app.DrawData.(selectedItemG) == selected_group(i));
                        y=app.DrawData.(selectedItemY)(app.DrawData.(selectedItemG) == selected_group(i));
                        stem(x,y,...
                        '--o',...
                        'MarkerSize',5,...
                        'LineWidth',0.75)
                        hold on
                    end
            
                    datacursormode on;
                    dcm_obj = datacursormode(gcf);
                    set(dcm_obj,'DisplayStyle','window',...
                         'SnapToDataVertex','off','Enable','on')
                    sz = 180;
                    hold on
                    x=app.DrawData.(selectedItemX);
                    y=app.DrawData.(selectedItemY);
                    s = scatter(x,y,sz,'o','w')
                    xlabel(selectedItemX);
                    ylabel(selectedItemY);
            
                    s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
                    s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
            
                    fn = fieldnames(app.DrawData);
                    for datatipindex=1:length(fieldnames(app.DrawData))
                        s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
                    end
                    legend({char(selected_group)})
                    hold off
            
                case 9
            
                    selected_group = categories(app.DrawData.(selectedItemG));
                    for i=1:length(selected_group)
                        x=app.DrawData.(selectedItemX)(app.DrawData.(selectedItemG) == selected_group(i));
                        y=app.DrawData.(selectedItemY)(app.DrawData.(selectedItemG) == selected_group(i));
                        z=app.DrawData.(selectedItemZ)(app.DrawData.(selectedItemG) == selected_group(i));
                        stem3(x,y,z,'o');
                        hold on
                    end
                    datacursormode on;
                    dcm_obj = datacursormode(gcf);
                    set(dcm_obj,'DisplayStyle','window',...
                        'SnapToDataVertex','off','Enable','on')
            
                    sz = 180;
                    x=app.DrawData.(selectedItemX);
                    y=app.DrawData.(selectedItemY);
                    z=app.DrawData.(selectedItemZ);
                    s = scatter3(x,y,z,sz,'o','w')
                    xlabel(selectedItemX);
                    ylabel(selectedItemY);
                    zlabel(selectedItemZ);
            
                    s.DataTipTemplate.DataTipRows(1).Label = selectedItemX;
                    s.DataTipTemplate.DataTipRows(2).Label = selectedItemY;
                    s.DataTipTemplate.DataTipRows(3).Label = selectedItemZ;
            
                    fn = fieldnames(app.DrawData);
                    for   datatipindex=1:length(fieldnames(app.DrawData))
                        s.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(string(fn(datatipindex)),app.DrawData.(fn{datatipindex}));
                    end
                    legend({char(selected_group)})
                    hold off
            
                case 10
            
                    data = app.UIDataTable.Data; % get list of data from disp UItable
                    cell_rows = size(data)              % number of rows of UItable
                    for i=1:cell_rows(1);           % checking 1 row for selected items
                        selectedItems(i,1) = cell2mat(data(i,1))
                    end
            
                    selectedItems = logical(selectedItems); % ==>> transform to logical array
                    k = find(selectedItems);                %===find selected items index
            
                    if (app.RB_Axes1_OriginalData.Value == 1)
                        for j=1:length(k)
                            SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                        end
                    else
                        for j=1:length(k)
                            SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                        end
                    end
                    if (app.RB_Axes1_FilteredData.Value == 1)
                        for j=1:length(k)
                        SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                        end
                    else
                        for j=1:length(k)
                        SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                        end
                    end
            
                    for i=1:height(SortedTable) %length selected elements
                        BarParameters(i) = app.DrawData.(selectedItemX)(i)
                    end
                    bar(BarParameters,0.5)
                    ylabel(selectedItemX);
                    hold off
            
                case 11
                    data = app.UIDataTable.Data; % get list of data from disp UItable
                    cell_rows = size(data)              % number of rows of UItable
            
                    for i=1:cell_rows(1);           % checking 1 row for selected items
                        selectedItems(i,1) = cell2mat(data(i,1))
                    end
            
                    selectedItems = logical(selectedItems); % ==>> transform to logical array
                    k = find(selectedItems);                %===find selected items index
            
                    if (app.RB_Axes1_OriginalData.Value == 1)
                        for j=1:length(k)
                            SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                        end
                    else
                        for j=1:length(k)
                            SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                        end
                    end
                    if (app.RB_Axes1_FilteredData.Value == 1)
                        for j=1:length(k)
                            SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                        end
                    else
                        for j=1:length(k)
                            SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                        end
                    end
            
                    for i=1:height(SortedTable) %length selected elements
                        BarParameters(i) = app.DrawData.(selectedItemX)(i)
                    end
                    barh(BarParameters,0.5)
                    xlabel(selectedItemX);
                    hold off
            
            case 12
            
            bar_params = app.jCBListAxes2.getCheckedValues; % get list of selected params
            cell_param = {}; % clear list
            for i=1:size(bar_params)
                cell_param(:,i) = cellstr(bar_params.get(i-1)) % ---> to string array
            end
            data = app.UIDataTable.Data; % get list of data from disp UItable
            cell_rows = size(data)              % number of rows of UItable
            for i=1:cell_rows(1);           % checking 1 row for selected items
                selectedItems(i,1) = cell2mat(data(i,1))
            end
            selectedItems = logical(selectedItems); % ==>> transform to logical array
            k = find(selectedItems);                %===find selected items index
            
            if (app.RB_Axes1_OriginalData.Value == 1)
                for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            if (app.RB_Axes1_FilteredData.Value == 1)
                for j=1:length(k)
                    SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            else
                for j=1:length(k)
                    SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                end
            end
            headers = SortedTable.Properties.VariableNames; %get VariableNames of sorted table
            
            for i=1:length(headers)
                app.DrawData.(headers{i}) = table2array(SortedTable(:,i));
            end
                % creating structure from selected table elements
            for i=1:length(headers)
                if (iscell(SortedTable.(headers{i})(1)))
                    app.DrawData.(headers{i}) = categorical(app.DrawData.(headers{i})); %make cells - categorical
                end
            end
            app.DrawData.(headers{1}) = categorical(app.DrawData.(headers{1})); % Make year - categorical
            % Creating  Matrix of elements for Spider Chart Element (i - number of elements, j)
            for i=1:height(SortedTable)   %height(SortedTable) % Selected elements
                for j=1:length(cell_param)
                    Element(i,j) = app.DrawData.(char(cell_param(j)))(i)
                end
            end
            bar(Element,'grouped')
            legend({char(cell_param)})
            hold off
            
            case 13
            
                bar_params = app.jCBListAxes2.getCheckedValues; % get list of selected params
            
                cell_param = {}; % clear list
                for i=1:size(bar_params)
                    cell_param(:,i) = cellstr(bar_params.get(i-1)) % ---> to string array
                end
            
                data = app.UIDataTable.Data; % get list of data from disp UItable
                cell_rows = size(data)              % number of rows of UItable
            
                for i=1:cell_rows(1);           % checking 1 row for selected items
                    selectedItems(i,1) = cell2mat(data(i,1))
                end
            
                selectedItems = logical(selectedItems); % ==>> transform to logical array
                k = find(selectedItems);                %===find selected items index
            
            
                if (app.RB_Axes1_OriginalData.Value == 1)
                    for j=1:length(k)
                        SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                else
                    for j=1:length(k)
                        SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                end
                if (app.RB_Axes1_FilteredData.Value == 1)
                    for j=1:length(k)
                        SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                else
                    for j=1:length(k)
                        SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                end
                headers = SortedTable.Properties.VariableNames; %get VariableNames of sorted table
            
                for i=1:length(headers)
                    app.DrawData.(headers{i}) = table2array(SortedTable(:,i));
                end
                % creating structure from selected table elements
                for i=1:length(headers)
                    if (iscell(SortedTable.(headers{i})(1)))
                        app.DrawData.(headers{i}) = categorical(app.DrawData.(headers{i})); %make cells - categorical
                    end
                end
                app.DrawData.(headers{1}) = categorical(app.DrawData.(headers{1})); % Make year - categorical
                    % Creating  Matrix of elements for Spider Chart Element (i - number of elements, j)
                for i=1:height(SortedTable)   %height(SortedTable) % Selected elements
                    for j=1:length(cell_param)
                        Element(i,j) = app.DrawData.(char(cell_param(j)))(i)
                    end
                end
                barh(Element,'grouped')
                legend({char(cell_param)})
                hold off
            case 14
            
                paral_params = app.jCBListAxes2.getCheckedValues; % get list of selected params
            
                cell_param = {}; % clear list
                for i=1:size(paral_params)
                    cell_param(:,i) = cellstr(paral_params.get(i-1)) % ---> to string array
                end
            
                data = app.UIDataTable.Data; % get list of data from disp UItable
                cell_rows = size(data)              % number of rows of UItable
                for i=1:cell_rows(1);           % checking 1 row for selected items
                    selectedItems(i,1) = cell2mat(data(i,1))
                end
            
                selectedItems = logical(selectedItems); % ==>> transform to logical array
                k = find(selectedItems);                %===find selected items index
            
                if (app.RB_Axes1_OriginalData.Value == 1)
                    for j=1:length(k)
                        SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                else
                    for j=1:length(k)
                        SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                end
                if (app.RB_Axes1_FilteredData.Value == 1)
                    for j=1:length(k)
                        SortedTable(j,:) = app.FilteredDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                else
                    for j=1:length(k)
                        SortedTable(j,:) = app.OriginalDataTable(k(j),:)    % creating new Table from selected elements in UITable
                    end
                end
                for j=1:length(cell_param)
                    for i=1:height(SortedTable)
                        ParalelStruct.(cell_param{j})(i) = app.DrawData.(cell_param{j})(i)
                    end
                    ParalelStruct.(cell_param{j}) = transpose(ParalelStruct.(cell_param{j}))
                end
                ParallelTable = struct2table(ParalelStruct);
                figure;
                p = parallelplot(ParallelTable);
                p.GroupVariable = selectedItemG;
                p.LineStyle = {'-'}
                p.LineWidth = [1.5]
            
            end
        end

        % Value changed function: ExpandTable
        function ExpandTable_Callback(app, event)
            
            if app.ExpandTable.Value == 1
                setpixelposition(app.UIDataTable, [0 193 1291 490])
                uistack(app.UIDataTable, 'top');
            else
                setpixelposition(app.UIDataTable, [0 573 1291 111])
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButton_Callback(app, event)
            headers = app.OriginalDataTable.Properties.VariableNames;
            [filename, pathname] = uiputfile('*.xls', 'Choose a file name');
            Data = app.UIDataTable.Data;
            ExportTable = cell2table(Data);
            ExportTable.Properties.VariableNames = ['X' headers];
            ExportTable = removevars(ExportTable,'X')
            outname = fullfile(pathname, filename);
            writetable(ExportTable, outname);
        end

        % Button pushed function: FilterDataButton
        function FilterDataButton_Callback(app, event)
            %clear filtered structure
            app.FilteredDataTable(:,:) = [];
            FilterIndexArray = [];
            %===============================================================================================
            CatFilter_1 = app.jCBListFilter1.getCheckedValues; % Get selected Params for 1st cat filter
            if size(CatFilter_1)== 0        %If - null select all
                app.ModelFilter1 = app.jCBListFilter1.getCheckModel;
                app.ModelFilter1.checkAll;
                CatFilter_1 = app.jCBListFilter1.getCheckedValues;
            end
            
            Cell_CatFilter_1 = {}; %Cell Cat Filter 1 - for substitute in fileds of structure
            for i=1:size(CatFilter_1)
                Cell_CatFilter_1(:,i) = cellstr(CatFilter_1.get(i-1))
            end
            %GetNameField of selected cat param
            idItemF1 = get(app.PopUpMenuFilter1,'Value');
            itemF1 = get(app.PopUpMenuFilter1,'String');
            selectedItemF1 = itemF1{idItemF1};
            for i=1:length(Cell_CatFilter_1) % Creating structur with indexes
                    StructIndex_F1{i}.index = find(app.OriginalStructure.(selectedItemF1) == Cell_CatFilter_1(i));
            end
            %Creating Index Massive for 1 Cat Filter
            k = 1;
            for i=1:size(CatFilter_1)
                 for j=1:length(StructIndex_F1{1,i}.index)
                     IndexMassiveCat_1(k) = StructIndex_F1{1,i}.index(j);
                     k=k+1;
               end
            end
            %==========================================================================================
            %========================Second filter=====================================================
            CatFilter_2 = app.jCBListFilter2.getCheckedValues;
            if size(CatFilter_2)== 0
                app.ModelFilter2 = app.jCBListFilter2.getCheckModel;
                app.ModelFilter2.checkAll;
                CatFilter_2 = app.jCBListFilter2.getCheckedValues;
            end
            
            Cell_CatFilter_2 = {};
            for i=1:size(CatFilter_2)
                Cell_CatFilter_2(:,i) = cellstr(CatFilter_2.get(i-1))
            end
            %GetNameField of selected cat param
            idItemF2 = get(app.PopUpMenuFilter2,'Value');
            itemF2 = get(app.PopUpMenuFilter2,'String');
            selectedItemF2 = itemF2{idItemF2};
            for i=1:length(Cell_CatFilter_2)
                    StructIndex_F2{i}.index = find(app.OriginalStructure.(selectedItemF2) == Cell_CatFilter_2(i));
            end
            k=1;
            for i=1:size(CatFilter_2)
                 for j=1:length(StructIndex_F2{1,i}.index)
                     IndexMassiveCat_2(k) = StructIndex_F2{1,i}.index(j);
                     k=k+1;
               end
            end
            %====================================================================================
            %======================Third Cat Filter==============================================
            CatFilter_3 = app.jCBListFilter3.getCheckedValues;
            if size(CatFilter_3)== 0
                app.ModelFilter3 = app.jCBListFilter3.getCheckModel;
                app.ModelFilter3.checkAll;
                CatFilter_3 = app.jCBListFilter3.getCheckedValues;
            end
            
            Cell_CatFilter_3 = {};
            for i=1:size(CatFilter_3)
                Cell_CatFilter_3(:,i) = cellstr(CatFilter_3.get(i-1))
            end
            %GetNameField of selected cat param
            idItemF3 = get(app.PopUpMenuFilter3,'Value');
            itemF3 = get(app.PopUpMenuFilter3,'String');
            selectedItemF3 = itemF3{idItemF3};
            for i=1:length(Cell_CatFilter_3)
                    StructIndex_F3{i}.index = find(app.OriginalStructure.(selectedItemF3) == Cell_CatFilter_3(i));
            end
            k=1;
            for i=1:size(CatFilter_3)
                 for j=1:length(StructIndex_F3{1,i}.index)
                     IndexMassiveCat_3(k) = StructIndex_F3{1,i}.index(j);
                     k=k+1;
               end
            end
            %====================================================================================
            %=================Nuber Filters -First===============================================
            minValueF1 = str2double(get(app.F1MinVal, 'String'));
            if isnan(minValueF1)==1
               minValueF1 = -1000000
            end
            maxValueF1 = str2double(get(app.F1MaxVal, 'String'));
            if isnan(maxValueF1)==1
               maxValueF1 = 1000000
            end
            idNumF1 = get(app.PopUpNumFilter1,'Value');
            itemNumF1 = get(app.PopUpNumFilter1,'String');
            selectedNumF1 = itemNumF1{idNumF1};
            
            IndexMassiveNum_1 = find(...
                              app.OriginalDataTable.(selectedNumF1)>=minValueF1...
                            & app.OriginalDataTable.(selectedNumF1)<=maxValueF1) ;
            %=========================Second Number=============================================
            minValueF2 = str2double(get(app.F2MinVal, 'String'));
            if isnan(minValueF2)==1
               minValueF2 = -1000000
            end
            maxValueF2 = str2double(get(app.F2MaxVal, 'String'));
            if isnan(maxValueF2) == 1
               maxValueF2 = 1000000
            end
            idNumF2 = get(app.PopUpNumFilter2,'Value');
            itemNumF2 = get(app.PopUpNumFilter2,'String');
            selectedNumF2 = itemNumF2{idNumF2};
            
            IndexMassiveNum_2 = find(...
                              app.OriginalDataTable.(selectedNumF2)>=minValueF2...
                            & app.OriginalDataTable.(selectedNumF2)<=maxValueF2) ;
            %=========================Third Number=============================================
            minValueF3 = str2double(get(app.F3MinVal, 'String'));
            if isnan(minValueF3)==1
               minValueF3 = -1000000
            end
            maxValueF3 = str2double(get(app.F3MaxVal, 'String'));
            if isnan(maxValueF3)==1
               maxValueF3 = 1000000
            end
            idNumF3 = get(app.PopUpNumFilter3,'Value');
            itemNumF3 = get(app.PopUpNumFilter3,'String');
            selectedNumF3 = itemNumF3{idNumF3};
            
            IndexMassiveNum_3 = find(...
                              app.OriginalDataTable.(selectedNumF3)>=minValueF3...
                            & app.OriginalDataTable.(selectedNumF3)<=maxValueF3) ;
            %========================================================================
            % IndexMassiveCat_1
            % IndexMassiveCat_2
            % IndexMassiveCat_3
            %
            % IndexMassiveNum_1
            % IndexMassiveNum_2
            % IndexMassiveNum_3
            %===========
            %=======Mintersect - New function from file_ex = Create 1 array of double indexes
            FilterIndexArray = mintersect(IndexMassiveCat_1,IndexMassiveCat_2,IndexMassiveCat_3,IndexMassiveNum_1,IndexMassiveNum_2,IndexMassiveNum_3);
            %==========================================================================
            %========================Creating FilteredDataTable========================
            
            
            for j=1:length(FilterIndexArray)
                app.FilteredDataTable(j,:) = app.OriginalDataTable(FilterIndexArray(j),:);
            end
            %S======Creating Filtered Structure=======================================
            
            headers = app.OriginalDataTable.Properties.VariableNames;
            for i=1:length(headers)
                app.FilteredStructure.(headers{i}) = table2array(app.FilteredDataTable(:,i));
            end
            
            for i=1:length(headers)
            
                if (iscell(app.FilteredDataTable.(headers{i})(1)))
            
                    app.FilteredStructure.(headers{i}) = categorical(app.FilteredStructure.(headers{i})); %make cells - categorical
            
                end
            end
            app.FilteredStructure.(headers{1}) = categorical(app.FilteredStructure.(headers{1})); % Make year - categorical
            % app.FilteredStructure and app.FilteredDataTable are in handles
            %==========================================================================================
            set(app.UIDataTable, 'Data', {}); % clear uitable;
            %=========================Display Filtered Data Table in UIDataTable=======================
            
            selector = table2cell(array2table(true(height(app.FilteredDataTable),1)));
            
            uitable_headers = ['X' headers];
            
            table_cell = table2cell(app.FilteredDataTable);
            data_table = [selector table_cell];
            
            app.UIDataTable.Data = data_table;
            app.UIDataTable.ColumnName = uitable_headers;
            app.UIDataTable.SelectionHighlight = 'on';
            
            mtable = app.UIDataTable;
            jscrollpane = findjobj(mtable);
            jtable = jscrollpane.getViewport.getView;
            jtable.setSortable(true);
            jtable.setColumnAutoResizable(true);
            
            guidata(hObject, handles);
            app.output = hObject;
        end

        % Value changed function: GridAxes1CheckBox
        function GridAxes1CheckBox_Callback(app, event)
                axes(app.axes1);
                if app.GridAxes1CheckBox.Value == true
                grid on
                grid minor
                end
                if app.GridAxes1CheckBox.Value == false
                grid off
                end
        end

        % Value changed function: GridAxes2CheckBox
        function GridAxes2CheckBox_Callback(app, event)
            
                axes(app.axes2);
                if app.GridAxes2CheckBox.Value == true
                grid on;
                grid minor;
                end
                if app.GridAxes2CheckBox.Value == false
                grid off
                end
        end

        % Value changed function: InvX_Axes1
        function InvX_Axes1_Callback(app, event)
                axes(app.axes1);
                if app.InvX_Axes1.Value == true
                set(gca, 'XDir','reverse');
                else
                set(gca, 'XDir','normal')
                end
        end

        % Value changed function: InvY_Axes1
        function InvY_Axes1_Callback(app, event)
                axes(app.axes1);
                if app.InvY_Axes1.Value == true
                set(gca, 'YDir','reverse');
                else
                set(gca, 'YDir','normal')
                end
        end

        % Value changed function: InvZ_Axes1
        function InvZ_Axes1_Callback(app, event)
                axes(app.axes1);
                if app.InvZ_Axes1.Value == true
                set(gca, 'ZDir','reverse');
                else
                set(gca, 'ZDir','normal')
                end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButton_Callback(app, event)
            
            SourceFile = uigetfile('.xls'); %
            set(app.FileNameText,'Text', SourceFile);
            
            OriginalDataTable = readtable(SourceFile, 'PreserveVariableNames', false);
            
            headers = OriginalDataTable.Properties.VariableNames;
            
            for  i=1:length(headers)
                       OriginalStructure.(headers{i}) = table2array(OriginalDataTable(:,i));
            end
            
            for i=1:length(headers)
                    if (iscell(OriginalDataTable.(headers{i})(1)))
                    OriginalStructure.(headers{i}) = categorical(OriginalStructure.(headers{i})); %make cells - categorical
                    end
            end
            OriginalStructure.(headers{1}) = categorical(OriginalStructure.(headers{1})); % Make year - categorical
            %===================================================================================================================
            
            FilteredDataTable = OriginalDataTable;
            FilteredStructure =  OriginalStructure;
            
            %========================================
            
            % Creatin UItable with selecting
            selector = table2cell(array2table(true(height(OriginalDataTable),1)));
            uitable_headers = ['X' headers];
            table_cell = table2cell(OriginalDataTable);
            
            data_table = [selector table_cell];
            
            app.UIDataTable.Data = data_table;
            app.UIDataTable.ColumnName = uitable_headers;
            
            mtable = app.UIDataTable; % makeing uitable sortable
            jscrollpane = findjobj(mtable); % add findjobj.m to folder
            jtable = jscrollpane.getViewport.getView;
            jtable.setSortable(true);		% or: set(jtable,'Sortable','on');
            jtable.setColumnAutoResizable(true);
            
            SetGroups(app.PopUpMenuFilter1,eventdata, handles); %fill categorical filters 1
            SetGroups(app.PopUpMenuFilter2,eventdata, handles);%  2
            SetGroups(app.PopUpMenuFilter3,eventdata, handles);% 3
            
            SetFilterNumber(app.PopUpNumFilter1,eventdata, handles);% fill number filters 1
            SetFilterNumber(app.PopUpNumFilter2,eventdata, handles);% 2
            SetFilterNumber(app.PopUpNumFilter3,eventdata, handles);%  3
            
            SetChartTypes(app.ChartTypesAxes_1,eventdata, handles); % chart types 1
            SetChartTypes(app.ChartTypesAxes_2,eventdata, handles); % 2
            
            SetDataToAxis(app.XDataPopUpAxes_1, eventdata, handles); %set variables 1 to XYZ selector
            SetDataToAxis(app.YDataPopUpAxes_1, eventdata, handles); %
            SetDataToAxis(app.ZDataPopUpAxes_1, eventdata, handles); %
            
            SetDataToAxis(app.XDataPopUpAxes_2, eventdata, handles); % set variables 1 to XYZ selector
            SetDataToAxis(app.YDataPopUpAxes_2, eventdata, handles); %
            SetDataToAxis(app.ZDataPopUpAxes_2, eventdata, handles); %
            
            SetGroups(app.PopUpGroupAxes_1, eventdata, handles);
            SetGroups(app.PopUpGroupAxes_2, eventdata, handles);
            
            app.output = hObject;
            guidata(hObject, handles);
        end

        % Value changed function: LogX_Axes1
        function LogX_Axes1_Callback(app, event)
                axes(app.axes1);
                if app.LogX_Axes1.Value == true
                set(gca, 'XScale', 'log');
                else
                set(gca, 'XScale', 'linear');
                end
        end

        % Value changed function: LogY_Axes1
        function LogY_Axes1_Callback(app, event)
                axes(app.axes1);
                if app.LogY_Axes1.Value == true
                set(gca, 'YScale', 'log');
                else
                set(gca, 'YScale', 'linear');
                end
        end

        % Value changed function: LogZ_Axes1
        function LogZ_Axes1_Callback(app, event)
                axes(app.axes1);
                if app.LogZ_Axes1.Value == true
                set(gca, 'ZScale', 'log');
                else
                set(gca, 'ZScale', 'linear');
                end
        end

        % Value changed function: PopUpMenuFilter1
        function PopUpMenuFilter1_Callback(app, event)
            
            idItem = get(app.PopUpMenuFilter1,'Value');
            item = get(app.PopUpMenuFilter1,'String');
            selectedItem = item{idItem};
            listCatParam = categories(app.OriginalStructure.(selectedItem));
            
            app.jListFilter1 = java.util.ArrayList;  % any java.util.List will be ok
            
            for  i=1:length(listCatParam)
                 app.jListFilter1.add(i-1, char(listCatParam(i)));
            end
            
            app.jCBListFilter1 = com.mathworks.mwswing.checkboxlist.CheckBoxList(app.jListFilter1);
            app.jScrollPane1Filter1 = com.mathworks.mwswing.MJScrollPane(app.jCBListFilter1);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Filter1,[1050,445,211,85],gcf);
            
            app.output = hObject;
            guidata(hObject, handles);
        end

        % Value changed function: PopUpMenuFilter2
        function PopUpMenuFilter2_Callback(app, event)
            
            idItem = get(app.PopUpMenuFilter2,'Value');
            item = get(app.PopUpMenuFilter2,'String');
            selectedItem = item{idItem};
            listCatParam = categories(app.OriginalStructure.(selectedItem));
            
            app.jListFilter2 = java.util.ArrayList;  % any java.util.List will be ok
             for  i=1:length(listCatParam)
                 app.jListFilter2.add(i-1, char(listCatParam(i)));
            end
            
            app.jCBListFilter2 = com.mathworks.mwswing.checkboxlist.CheckBoxList(app.jListFilter2);
            app.jScrollPane1Filter2 = com.mathworks.mwswing.MJScrollPane(app.jCBListFilter2);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Filter2,[1050,336,211,85],gcf);
            
            app.output = hObject;
            guidata(hObject, handles);
        end

        % Value changed function: PopUpMenuFilter3
        function PopUpMenuFilter3_Callback(app, event)
            
            idItem = get(app.PopUpMenuFilter3,'Value');
            item = get(app.PopUpMenuFilter3,'String');
            selectedItem = item{idItem};
            listCatParam = categories(app.OriginalStructure.(selectedItem));
            
            app.jListFilter3 = java.util.ArrayList;  % any java.util.List will be ok
             for  i=1:length(listCatParam)
                 app.jListFilter3.add(i-1, char(listCatParam(i)));
            end
            
            app.jCBListFilter3 = com.mathworks.mwswing.checkboxlist.CheckBoxList(app.jListFilter3);
            app.jScrollPane1Filter3 = com.mathworks.mwswing.MJScrollPane(app.jCBListFilter3);
            [jhScroll,hContainer] = javacomponent(app.jScrollPane1Filter3,[1050,227,211,85],gcf);
            
            app.output = hObject;
            guidata(hObject, handles);
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBox_Callback(app, event)
            
            if app.SelectAllCheckBox.Value == true
                  app.UIDataTable.Data(:,1) = {true};
            else
                  app.UIDataTable.Data(:,1) = {false};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'RFDataAnalys';
            app.axes2 = uiaxes(app.figure1);
            app.axes1 = uiaxes(app.figure1);
            app.GridAxes1CheckBox = uicheckbox(app.figure1);
            app.GridAxes1CheckBox.ValueChangedFcn = createCallbackFcn(app, @GridAxes1CheckBox_Callback, true);
            app.GridAxes1CheckBox.Text = 'Grid ON/OFF';
            app.GridAxes2CheckBox = uicheckbox(app.figure1);
            app.GridAxes2CheckBox.ValueChangedFcn = createCallbackFcn(app, @GridAxes2CheckBox_Callback, true);
            app.GridAxes2CheckBox.Text = 'Grid ON/OFF';
            app.LoadDataButton = uibutton(app.figure1, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButton_Callback, true);
            app.LoadDataButton.Text = 'Load Data .xls';
            app.FileNameText = uilabel(app.figure1);
            app.FileNameText.Text = 'Loaded filename';
            app.UIDataTable = uitable(app.figure1);
            app.UIDataTable.ColumnName = {'1'; '2'};
            app.UIDataTable.ColumnEditable = [true false];
            app.ChartTypesAxes_1 = uidropdown(app.figure1);
            app.ChartTypesAxes_1.Items = {'Select chart Type'};
            app.ChartTypesAxes_1.ValueChangedFcn = createCallbackFcn(app, @ChartTypesAxes_1_Callback, true);
            app.ChartTypesAxes_1.Value = 'Select chart Type';
            app.XDataPopUpAxes_1 = uidropdown(app.figure1);
            app.XDataPopUpAxes_1.Items = {'Pop-up Menu'};
            app.XDataPopUpAxes_1.Value = 'Pop-up Menu';
            app.YDataPopUpAxes_1 = uidropdown(app.figure1);
            app.YDataPopUpAxes_1.Items = {'Pop-up Menu'};
            app.YDataPopUpAxes_1.Value = 'Pop-up Menu';
            app.ZDataPopUpAxes_1 = uidropdown(app.figure1);
            app.ZDataPopUpAxes_1.Items = {'Pop-up Menu'};
            app.ZDataPopUpAxes_1.Value = 'Pop-up Menu';
            app.PopUpGroupAxes_1 = uidropdown(app.figure1);
            app.PopUpGroupAxes_1.Items = {'Select Group'};
            app.PopUpGroupAxes_1.Value = 'Select Group';
            app.LogX_Axes1 = uicheckbox(app.figure1);
            app.LogX_Axes1.ValueChangedFcn = createCallbackFcn(app, @LogX_Axes1_Callback, true);
            app.LogX_Axes1.Text = 'Log(X)';
            app.LogY_Axes1 = uicheckbox(app.figure1);
            app.LogY_Axes1.ValueChangedFcn = createCallbackFcn(app, @LogY_Axes1_Callback, true);
            app.LogY_Axes1.Text = 'Log(Y)';
            app.LogZ_Axes1 = uicheckbox(app.figure1);
            app.LogZ_Axes1.ValueChangedFcn = createCallbackFcn(app, @LogZ_Axes1_Callback, true);
            app.LogZ_Axes1.Text = 'Log(Z)';
            app.InvX_Axes1 = uicheckbox(app.figure1);
            app.InvX_Axes1.ValueChangedFcn = createCallbackFcn(app, @InvX_Axes1_Callback, true);
            app.InvX_Axes1.Text = 'Invert(X)';
            app.InvY_Axes1 = uicheckbox(app.figure1);
            app.InvY_Axes1.ValueChangedFcn = createCallbackFcn(app, @InvY_Axes1_Callback, true);
            app.InvY_Axes1.Text = 'Invert(Y)';
            app.InvZ_Axes1 = uicheckbox(app.figure1);
            app.InvZ_Axes1.ValueChangedFcn = createCallbackFcn(app, @InvZ_Axes1_Callback, true);
            app.InvZ_Axes1.Text = 'Invert(Z)';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel5 = uipanel(app.figure1);
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.uibuttongroup1.Title = 'Data Source';
            app.RB_Axes1_OriginalData = uiradiobutton(app.uibuttongroup1);
            app.RB_Axes1_OriginalData.Text = 'Original Data';
            app.RB_Axes1_OriginalData.Value = true;
            app.RB_Axes1_FilteredData = uiradiobutton(app.uibuttongroup1);
            app.RB_Axes1_FilteredData.Text = 'Filtered Data';
            app.DrawButton_Axes1 = uibutton(app.figure1, 'push');
            app.DrawButton_Axes1.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_Axes1_Callback, true);
            app.DrawButton_Axes1.Text = 'Draw Graph';
            app.ChartTypesAxes_2 = uidropdown(app.figure1);
            app.ChartTypesAxes_2.Items = {'Select chart Type'};
            app.ChartTypesAxes_2.ValueChangedFcn = createCallbackFcn(app, @ChartTypesAxes_2_Callback, true);
            app.ChartTypesAxes_2.Value = 'Select chart Type';
            app.XDataPopUpAxes_2 = uidropdown(app.figure1);
            app.XDataPopUpAxes_2.Items = {'Pop-up Menu'};
            app.XDataPopUpAxes_2.Value = 'Pop-up Menu';
            app.YDataPopUpAxes_2 = uidropdown(app.figure1);
            app.YDataPopUpAxes_2.Items = {'Pop-up Menu'};
            app.YDataPopUpAxes_2.Value = 'Pop-up Menu';
            app.ZDataPopUpAxes_2 = uidropdown(app.figure1);
            app.ZDataPopUpAxes_2.Items = {'Pop-up Menu'};
            app.ZDataPopUpAxes_2.Value = 'Pop-up Menu';
            app.PopUpGroupAxes_2 = uidropdown(app.figure1);
            app.PopUpGroupAxes_2.Items = {'Select Group'};
            app.PopUpGroupAxes_2.Value = 'Select Group';
            app.LogX_Axes2 = uicheckbox(app.figure1);
            app.LogX_Axes2.Text = 'Log(X)';
            app.LogY_Axes2 = uicheckbox(app.figure1);
            app.LogY_Axes2.Text = 'Log(Y)';
            app.LogZ_Axes2 = uicheckbox(app.figure1);
            app.LogZ_Axes2.Text = 'Log(Z)';
            app.InvX_Axes2 = uicheckbox(app.figure1);
            app.InvX_Axes2.Text = 'Invert(X)';
            app.InvY_Axes2 = uicheckbox(app.figure1);
            app.InvY_Axes2.Text = 'Invert(Y)';
            app.InvZ_Axes2 = uicheckbox(app.figure1);
            app.InvZ_Axes2.Text = 'Invert(Z)';
            app.DrawButton_Axes2 = uibutton(app.figure1, 'push');
            app.DrawButton_Axes2.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_Axes2_Callback, true);
            app.DrawButton_Axes2.Text = 'Draw Graph';
            app.uibuttongroup2 = uibuttongroup(app.figure1);
            app.uibuttongroup2.Title = 'Data Source';
            app.RB_Axes2_OriginalData = uiradiobutton(app.uibuttongroup2);
            app.RB_Axes2_OriginalData.Text = 'Original Data';
            app.RB_Axes2_OriginalData.Value = true;
            app.RB_Axes2_FilteredData = uiradiobutton(app.uibuttongroup2);
            app.RB_Axes2_FilteredData.Text = 'Filterd Data';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Category Filters (select)';
            app.PopUpMenuFilter1 = uidropdown(app.figure1);
            app.PopUpMenuFilter1.Items = {};
            app.PopUpMenuFilter1.ValueChangedFcn = createCallbackFcn(app, @PopUpMenuFilter1_Callback, true);
            app.PopUpMenuFilter1.Value = {};
            app.PopUpMenuFilter2 = uidropdown(app.figure1);
            app.PopUpMenuFilter2.Items = {};
            app.PopUpMenuFilter2.ValueChangedFcn = createCallbackFcn(app, @PopUpMenuFilter2_Callback, true);
            app.PopUpMenuFilter2.Value = {};
            app.PopUpMenuFilter3 = uidropdown(app.figure1);
            app.PopUpMenuFilter3.Items = {};
            app.PopUpMenuFilter3.ValueChangedFcn = createCallbackFcn(app, @PopUpMenuFilter3_Callback, true);
            app.PopUpMenuFilter3.Value = {};
            app.PopUpNumFilter1 = uidropdown(app.figure1);
            app.PopUpNumFilter1.Items = {};
            app.PopUpNumFilter1.Value = {};
            app.F1MinVal = uieditfield(app.figure1, 'text');
            app.F1MaxVal = uieditfield(app.figure1, 'text');
            app.text4 = uilabel(app.figure1);
            app.text4.Text = 'min. Val.';
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'max. Val.';
            app.PopUpNumFilter2 = uidropdown(app.figure1);
            app.PopUpNumFilter2.Items = {};
            app.PopUpNumFilter2.Value = {};
            app.F2MinVal = uieditfield(app.figure1, 'text');
            app.F2MaxVal = uieditfield(app.figure1, 'text');
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'min. Val.';
            app.text7 = uilabel(app.figure1);
            app.text7.Text = 'max. Val.';
            app.PopUpNumFilter3 = uidropdown(app.figure1);
            app.PopUpNumFilter3.Items = {};
            app.PopUpNumFilter3.Value = {};
            app.F3MinVal = uieditfield(app.figure1, 'text');
            app.F3MaxVal = uieditfield(app.figure1, 'text');
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'min. Val.';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = 'max. Val.';
            app.FilterDataButton = uibutton(app.figure1, 'push');
            app.FilterDataButton.ButtonPushedFcn = createCallbackFcn(app, @FilterDataButton_Callback, true);
            app.FilterDataButton.Text = 'Filter Data';
            app.uipanel7 = uipanel(app.figure1);
            app.ExportDataButton = uibutton(app.figure1, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_Callback, true);
            app.ExportDataButton.Text = 'Export Filterd Data .xls';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'Number Filters(min/max)';
            app.text11 = uilabel(app.figure1);
            app.text11.Text = {'RFDataAnalys v.0.1'; '1. Load data - .xls table file  2. Select data  3. Enjoy. '};
            app.SelectAllCheckBox = uicheckbox(app.figure1);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBox_Callback, true);
            app.SelectAllCheckBox.Text = 'Select/Deselect All';
            app.X1Label = uilabel(app.figure1);
            app.X1Label.Text = 'X';
            app.Y1Label = uilabel(app.figure1);
            app.Y1Label.Text = 'Y';
            app.Z1Label = uilabel(app.figure1);
            app.Z1Label.Text = 'Z';
            app.X2Label = uilabel(app.figure1);
            app.X2Label.Text = 'X';
            app.Y2Label = uilabel(app.figure1);
            app.Y2Label.Text = 'Y';
            app.Z2Label = uilabel(app.figure1);
            app.Z2Label.Text = 'Z';
            app.ExpandTable = uibutton(app.figure1, 'state');
            app.ExpandTable.ValueChangedFcn = createCallbackFcn(app, @ExpandTable_Callback, true);
            app.ExpandTable.Text = 'Expand Table';
            app.Refline_Axes1 = uicheckbox(app.figure1);
            app.Refline_Axes1.Visible = 'off';
            app.Refline_Axes1.Text = 'Refline';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RFDataAnalys_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @RFDataAnalys_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end